classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RightPanel             matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        LeftPanel              matlab.ui.container.Panel
        GUARDARButton          matlab.ui.control.Button
        FileEditField          matlab.ui.control.EditField
        FileEditFieldLabel     matlab.ui.control.Label
        S2PLabel               matlab.ui.control.Label
        LEERButton             matlab.ui.control.Button
        ABCDButton             matlab.ui.control.Button
        TButton                matlab.ui.control.Button
        YButton                matlab.ui.control.Button
        ZButton                matlab.ui.control.Button
        SButton                matlab.ui.control.Button
        ValuesSwitch           matlab.ui.control.Switch
        ValuesSwitchLabel      matlab.ui.control.Label
        PARAMETERSLabel        matlab.ui.control.Label
        RECTANGULARButton      matlab.ui.control.Button
        POLARButton            matlab.ui.control.Button
        SMITHButton            matlab.ui.control.Button
        GRAPHICSLabel          matlab.ui.control.Label
        FinalEditField         matlab.ui.control.NumericEditField
        FinalEditFieldLabel    matlab.ui.control.Label
        PasosEditField         matlab.ui.control.NumericEditField
        PasosEditFieldLabel    matlab.ui.control.Label
        FREQUECYANALYSISLabel  matlab.ui.control.Label
        InicialEditField       matlab.ui.control.NumericEditField
        InicialEditFieldLabel  matlab.ui.control.Label
        FrecuenciaButton       matlab.ui.control.Button
        NetlistButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        %% variables de Netlist
        G 
        n
        value_switch
        fileIn
        value_matrix
        z1_value
        z1_sym
        dt_sym
        dt_value
        s1_value
        s1_sym
        pushboton_Ntelist
        A_red1
        A_red_sym
        A_red_value
        f_min
        f_step
        f_max
        Name
        nLines
        arg3
        frequencies
        Y11
        Y12
        Y21
        Y22
        S11
        S12
        S21
        S22
ImpedanceM_Num
value_file

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)

                if strcmp(app.value_switch,'On')
                    Z=Y_to_Z(app.A_red_sym);
                    app.z1_value=arrayfun(@char, Z.Z, 'UniformOutput', false);
                    app.UITable.Data=app.z1_value;
                    app.z1_value=str2sym(app.z1_value);
                    app.dt_value=Z.dt;
                else
                    Z=Y_to_Z(app.A_red1);
                    app.z1_sym=arrayfun(@char, Z.Z, 'UniformOutput', false);
                    app.UITable.Data=app.z1_sym;
                    app.z1_sym=str2sym(app.z1_sym);
                    app.dt_sym=Z.dt;
                end
                app.SButton.Enable="on";
                app.ABCDButton.Enable='on';
        end

        % Button pushed function: NetlistButton
        function NetlistButtonPushed(app, event)
            Netlist_call=Netlist_2("PasaBajas.cir");
            app.UITable.Data=cell(size(Netlist_call.A_red));
            app.A_red1=arrayfun(@char, Netlist_call.A_red, 'UniformOutput', false);
            app.UITable.Data=(app.A_red1);
            %% definir funciones para mandar llamar despues
            app.G=Netlist_call.G;
            app.n=Netlist_call.n;
            app.A_red_sym=Netlist_call.A_red;
            app.Name=Netlist_call.Name;
            app.nLines=Netlist_call.nLines;
            app.arg3=Netlist_call.arg3;
            app.ZButton.Enable='on';
            app.YButton.Enable="on";

        end

        % Value changed function: ValuesSwitch
        function ValuesSwitchValueChanged(app, event)
            app.value_switch = app.ValuesSwitch.Value;
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            if strcmp(app.value_switch,'On')
                S=Z_to_S(app.z1_value);
                app.s1_value=arrayfun(@char, S.S, 'UniformOutput', false);
                app.UITable.Data=app.s1_value;
                app.s1_value=str2sym(app.s1_value);
            else
                S=Z_to_S(app.z1_sym);
                app.s1_sym=arrayfun(@char, S.S, 'UniformOutput', false);
                app.UITable.Data=app.s1_sym;
                app.s1_sym=str2sym(app.s1_sym);
            end
            app.TButton.Enable='on';
        end

        % Button pushed function: ABCDButton
        function ABCDButtonPushed(app, event)
            if strcmp(app.value_switch,'On')
                ABCD=Y_to_ABCD(app.A_red_sym,app.dt_value);
                ABCD1_value=arrayfun(@char, ABCD.ABCD, 'UniformOutput', false);
                app.UITable.Data=ABCD1_value;
            else
                ABCD=Y_to_ABCD(app.A_red1,app.dt_sym);
                ABCD1_sym=arrayfun(@char, ABCD.ABCD, 'UniformOutput', false);
                app.UITable.Data=ABCD1_sym;
            end
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            if strcmp(app.value_switch,'On')
                T=S_to_T(app.s1_value);
                T1_value=arrayfun(@char, T.T, 'UniformOutput', false);
                app.UITable.Data=T1_value;
            else
                T=S_to_T(app.s1_sym);
                T1_sym=arrayfun(@char,T.T, 'UniformOutput', false);
                app.UITable.Data=T1_sym;
            end
        end

        % Value changed function: InicialEditField
        function InicialEditFieldValueChanged(app, event)
            app.f_min = app.InicialEditField.Value;
            
        end

        % Value changed function: PasosEditField
        function PasosEditFieldValueChanged(app, event)
            app.f_step = app.PasosEditField.Value;
            
        end

        % Value changed function: FinalEditField
        function FinalEditFieldValueChanged(app, event)
            app.f_max = app.FinalEditField.Value;

            
        end

        % Button pushed function: FrecuenciaButton
        function FrecuenciaButtonPushed(app, event)
            frequencies1=Analisis_frequenci(app.Name,app.arg3,app.A_red_sym,app.nLines,app.f_min,app.f_max,app.f_step);
            app.frequencies=frequencies1.frequencies;
            app.Y11=frequencies1.Y11;
            app.Y12=frequencies1.Y12;
            app.Y21=frequencies1.Y21;
            app.Y22=frequencies1.Y22;

            app.S11=frequencies1.S11;
            app.S12=frequencies1.S12;
            app.S21=frequencies1.S21;
            app.S22=frequencies1.S22;

            app.ValuesSwitch.Enable="on";

            Netlist_value=Matriz_values(app.Name,app.A_red_sym,app.arg3,app.frequencies,app.nLines);
            app.ImpedanceM_Num=Netlist_value.ImpedanceM_Num;
            app.A_red_sym=arrayfun(@char, app.ImpedanceM_Num, 'UniformOutput', false);
        end

        % Button pushed function: RECTANGULARButton
        function RECTANGULARButtonPushed(app, event)
           %GRAFICA 1
            app.UIAxes.Visible='on';
            app.UIAxes.Title.String=('|Y_{11}| vs Frecuencia');
            plot(app.UIAxes,app.frequencies, abs(app.Y11), 'b', 'LineWidth', 1.5);
            % GRAFICA 2
            app.UIAxes_2.Visible='on';
            app.UIAxes_2.Title.String=('|Y_{12}| vs Frecuencia');
            plot(app.UIAxes_2,app.frequencies, abs(app.Y12), 'r', 'LineWidth', 1.5);
            %GRAFICA 3
            app.UIAxes_3.Visible='on';
            app.UIAxes_3.Title.String=('|Y_{21}| vs Frecuencia');
            plot(app.UIAxes_3,app.frequencies, abs(app.Y21), 'r', 'LineWidth', 1.5);
            %GRAFICA 4
            app.UIAxes_4.Visible='on';
            app.UIAxes_4.Title.String=('|Y_{22}| vs Frecuencia');
            plot(app.UIAxes_4,app.frequencies, abs(app.Y22), 'r', 'LineWidth', 1.5);
        end

        % Button pushed function: SMITHButton
        function SMITHButtonPushed(app, event)
            
            frequenciesA = linspace(1e6, 100e6, length(app.S11)); % Frecuencias igualmente espaciadas
            frequenciesB = linspace(1e6, 100e6, length(app.S12)); % Frecuencias igualmente espaciadas
            frequenciesC = linspace(1e6, 100e6, length(app.S21)); % Frecuencias igualmente espaciadas
            frequenciesD = linspace(1e6, 100e6, length(app.S22)); % Frecuencias igualmente espaciadas
            % smithplot(frequencies, S11);
            figure;
            subplot(2, 2, 1);
            smithplot(frequenciesA, -app.S11);
            title('S(1,1)');
            grid on;
            subplot(2, 2, 2);
            smithplot(frequenciesB, -app.S12);
            title('S(1,2)');
            grid on;
            subplot(2, 2, 3);
            smithplot(frequenciesC, -app.S21);
            title('S(2,1)');
            grid on;
            subplot(2, 2, 4);
            smithplot(frequenciesD, -app.S22);
            title('S(2,2)');
            grid on;
            
            % Configuración general
            sgtitle('Gráfica de parámetros S en la Carta de Smith (1 GHz a 10 GHz)');

        end

        % Button pushed function: POLARButton
        function POLARButtonPushed(app, event)
            figure; % Crear nueva ventana
            polarplot(angle(app.Y11), abs(app.Y11), 'b', 'LineWidth', 1.5); hold on;
            polarplot(angle(app.Y12), abs(app.Y12), 'r', 'LineWidth', 1.5);
            polarplot(angle(app.Y21), abs(app.Y21), 'g', 'LineWidth', 1.5);
            polarplot(angle(app.Y22), abs(app.Y22), 'm', 'LineWidth', 1.5);
            hold off;
            title('Parámetros de Admitancia: Forma Polar');
            legend({'Y_{11}', 'Y_{12}', 'Y_{21}', 'Y_{22}'}, 'Location', 'Best');
        end

        % Button pushed function: LEERButton
        function LEERButtonPushed(app, event)
            S2P=Leer_S2P(app.value_file);

            S11_s2p=arrayfun(@num2str, S2P.S11, 'UniformOutput', false);
            S12_s2p=arrayfun(@num2str, S2P.S12, 'UniformOutput', false);
            S21_s2p=arrayfun(@num2str, S2P.S21, 'UniformOutput', false);
            S22_s2p=arrayfun(@num2str, S2P.S22, 'UniformOutput', false);
            t=[S11_s2p(:),S12_s2p(:),S21_s2p(:),S22_s2p(:)];
            app.UITable.Data=cell(size(t));
            app.UITable.Data=t;
            app.UITable.ColumnName={'S11','S12','S21','S22'};
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            app.value_file = app.FileEditField.Value;
            
            
        end

        % Button pushed function: GUARDARButton
        function GUARDARButtonPushed(app, event)
            T_frequencie='HZ';
            T_file='S';
            format='RI';
            [x,y]=size(app.S11);
            s11=reshape(app.S11,y,x);
            s12=reshape(app.S12,y,x);
            s21=reshape(app.S21,y,x);
            s22=reshape(app.S22,y,x);
            frequency=reshape(app.frequencies,y,x);
            Guardar_dato=Escribir_Y_Guardar_S2P(frequency,s11,s12,s21,s22, T_frequencie, T_file, format);
            Guardar_dato.writeToFile();
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            if strcmp(app.value_switch,'On')
                app.UITable.Data=app.A_red_sym;
            else
                 app.UITable.Data=app.A_red1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.NetlistButton = uibutton(app.LeftPanel, 'push');
            app.NetlistButton.ButtonPushedFcn = createCallbackFcn(app, @NetlistButtonPushed, true);
            app.NetlistButton.Text = 'Netlist';
            app.FrecuenciaButton = uibutton(app.LeftPanel, 'push');
            app.FrecuenciaButton.ButtonPushedFcn = createCallbackFcn(app, @FrecuenciaButtonPushed, true);
            app.FrecuenciaButton.Text = 'Frecuencia';
            app.InicialEditFieldLabel = uilabel(app.LeftPanel);
            app.InicialEditFieldLabel.Text = 'Inicial';
            app.InicialEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InicialEditField.ValueChangedFcn = createCallbackFcn(app, @InicialEditFieldValueChanged, true);
            app.FREQUECYANALYSISLabel = uilabel(app.LeftPanel);
            app.FREQUECYANALYSISLabel.Text = 'FREQUECY ANALYSIS';
            app.PasosEditFieldLabel = uilabel(app.LeftPanel);
            app.PasosEditFieldLabel.Text = 'Pasos';
            app.PasosEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PasosEditField.ValueChangedFcn = createCallbackFcn(app, @PasosEditFieldValueChanged, true);
            app.FinalEditFieldLabel = uilabel(app.LeftPanel);
            app.FinalEditFieldLabel.Text = 'Final';
            app.FinalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FinalEditField.ValueChangedFcn = createCallbackFcn(app, @FinalEditFieldValueChanged, true);
            app.GRAPHICSLabel = uilabel(app.LeftPanel);
            app.GRAPHICSLabel.Text = 'GRAPHICS';
            app.SMITHButton = uibutton(app.LeftPanel, 'push');
            app.SMITHButton.ButtonPushedFcn = createCallbackFcn(app, @SMITHButtonPushed, true);
            app.SMITHButton.Text = 'SMITH';
            app.POLARButton = uibutton(app.LeftPanel, 'push');
            app.POLARButton.ButtonPushedFcn = createCallbackFcn(app, @POLARButtonPushed, true);
            app.POLARButton.Text = 'POLAR';
            app.RECTANGULARButton = uibutton(app.LeftPanel, 'push');
            app.RECTANGULARButton.ButtonPushedFcn = createCallbackFcn(app, @RECTANGULARButtonPushed, true);
            app.RECTANGULARButton.Text = 'RECTANGULAR';
            app.PARAMETERSLabel = uilabel(app.LeftPanel);
            app.PARAMETERSLabel.Text = 'PARAMETERS';
            app.ValuesSwitchLabel = uilabel(app.LeftPanel);
            app.ValuesSwitchLabel.Text = 'Values';
            app.ValuesSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ValuesSwitch.ValueChangedFcn = createCallbackFcn(app, @ValuesSwitchValueChanged, true);
            app.ValuesSwitch.Enable = 'off';
            app.SButton = uibutton(app.LeftPanel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Enable = 'off';
            app.SButton.Text = 'S';
            app.ZButton = uibutton(app.LeftPanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Enable = 'off';
            app.ZButton.Text = 'Z';
            app.YButton = uibutton(app.LeftPanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Enable = 'off';
            app.YButton.Text = 'Y';
            app.TButton = uibutton(app.LeftPanel, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Enable = 'off';
            app.TButton.Text = 'T';
            app.ABCDButton = uibutton(app.LeftPanel, 'push');
            app.ABCDButton.ButtonPushedFcn = createCallbackFcn(app, @ABCDButtonPushed, true);
            app.ABCDButton.Enable = 'off';
            app.ABCDButton.Text = 'ABCD ';
            app.LEERButton = uibutton(app.LeftPanel, 'push');
            app.LEERButton.ButtonPushedFcn = createCallbackFcn(app, @LEERButtonPushed, true);
            app.LEERButton.Text = 'LEER';
            app.S2PLabel = uilabel(app.LeftPanel);
            app.S2PLabel.Text = 'S2P';
            app.FileEditFieldLabel = uilabel(app.LeftPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.LeftPanel, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.GUARDARButton = uibutton(app.LeftPanel, 'push');
            app.GUARDARButton.ButtonPushedFcn = createCallbackFcn(app, @GUARDARButtonPushed, true);
            app.GUARDARButton.Text = 'GUARDAR';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Title')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Title')
            app.UIAxes_4.Visible = 'off';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end